classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TestButton                   matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        DatabaseNameEditField        matlab.ui.control.EditField
        DatabaseNameEditFieldLabel   matlab.ui.control.Label
        DatabaseConnectionLamp       matlab.ui.control.Lamp
        DatabaseConnectionLampLabel  matlab.ui.control.Label
        PasswordEditField            matlab.ui.control.EditField
        PasswordEditFieldLabel       matlab.ui.control.Label
        UserEditField                matlab.ui.control.EditField
        UserEditFieldLabel           matlab.ui.control.Label
        ServerNameEditField          matlab.ui.control.EditField
        ServerNameEditFieldLabel     matlab.ui.control.Label
        ShowDataButton               matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        db % Database object
        table_tabs % Cell array of Tabs in TabGroup
    end
    
    methods (Access = private)
        
        function results = db_exists(app)
            % Returns true if app.db is a database, otherwise false.
            results = class(app.db) == "Database";
        end

        function color = set_database_color(app, connected)
            if connected
                color = 'green';
            else
                color = 'red';
            end

            app.DatabaseConnectionLamp.Color = color;
        end

        function show_db_data (app)
            % plot data from the db to the table

            app.table_tabs = cell(1, length(app.db.tables));
            table_name = app.db.get_table_name(1);
            app.table_tabs{1} = app.Tab;
            app.table_tabs{1}.Title = table_name;
            app.table_tabs{1}.Scrollable = true;
            uitable(app.table_tabs{1}, 'Data', app.db.selectIn(table_name, {'*'}));

            for i=2:length(app.db.tables)
                disp(i);
                table_name = app.db.get_table_name(i);
                app.table_tabs{i} = uitab(app.TabGroup, 'Title', table_name, 'Scrollable', true);
                uitable(app.table_tabs{i}, 'Data', app.db.selectIn(table_name, {'*'}));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % disconnect from the last database
            app.set_database_color(false);
            if app.db_exists
                app.db.disconnect();
            end
            clear app.db;
            
            % get input field values
            server_name = app.ServerNameEditField.Value;
            user_name = app.UserEditField.Value;
            pass = app.PasswordEditField.Value;
            db_name = app.DatabaseNameEditField.Value;
            
            try
                conn = connect(server_name, user_name, pass);
                app.db = Database(conn, db_name);
            catch e
                error(e.message);
                return;
            end

            app.set_database_color(true);
            app.show_db_data();
        end

        % Button pushed function: ShowDataButton
        function ShowDataButtonPushed(app, event)
            if ~app.db_exists()
                error("Not connected to database");
            end

            disp(app.db.selectIn('test', {'*'}));
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
              app.table_tabs = cell(1, 3);
              app.table_tabs{1} = app.Tab;
              app.table_tabs{1}.Title = 'Test';
              for i=2:1:3
                app.table_tabs{i} = uitab(app.TabGroup, 'Title', 'Test');
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ShowDataButton = uibutton(app.UIFigure, 'push');
            app.ShowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDataButtonPushed, true);
            app.ShowDataButton.Text = 'Show Data';
            app.ServerNameEditFieldLabel = uilabel(app.UIFigure);
            app.ServerNameEditFieldLabel.Text = 'Server Name';
            app.ServerNameEditField = uieditfield(app.UIFigure, 'text');
            app.UserEditFieldLabel = uilabel(app.UIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.DatabaseConnectionLampLabel = uilabel(app.UIFigure);
            app.DatabaseConnectionLampLabel.Text = 'Database Connection';
            app.DatabaseConnectionLamp = uilamp(app.UIFigure);
            app.DatabaseNameEditFieldLabel = uilabel(app.UIFigure);
            app.DatabaseNameEditFieldLabel.Text = 'Database Name';
            app.DatabaseNameEditField = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end